classdef appGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PHNTCHVNHNDNGKTPanel         matlab.ui.container.Panel
        Image3                       matlab.ui.control.Image
        CTBINPanel                   matlab.ui.container.Panel
        Image2                       matlab.ui.control.Image
        NGDNGCBINSXESDNGMATLABLabel  matlab.ui.control.Label
        excelBtn                     matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        cbinsButton                  matlab.ui.control.Button
        NHGCPanel                    matlab.ui.container.Panel
        anhBtn                       matlab.ui.control.Button
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: anhBtn
        function anhBtnButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Chọn ảnh để tải lên');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           app.Image.ImageSource = ImageFile
        end

        % Button pushed function: cbinsButton
        function cbinsButtonPushed(app, event)
           if isempty(app.Image.ImageSource)
        uialert(app.UIFigure, 'Hãy chọn một ảnh trước khi nhận dạng biển số.', 'Cảnh báo');
        return;
    end
    
    % Save the uploaded image to a temporary file if needed
    tempImagePath = fullfile(tempdir, 'temp_license_plate_image.png');
    imwrite(app.Image.ImageSource, tempImagePath);  % Save image temporarily

    % Call recognizeLicensePlate with the path of the saved image
    noPlate = Plate_detection(tempImagePath); 
    
    % Get the current time
    currentTime = datestr(now, 'dd-mmm-yyyy HH:MM:SS');
    
    % Add the result to the UITable
    newRow = {currentTime, noPlate, tempImagePath};  % Store the path to the temp image
    app.UITable.Data = [app.UITable.Data; newRow];  % Append to the table

   croppedPlatePath = fullfile(tempdir, 'cropped_plate.png');
    if exist(croppedPlatePath, 'file')
        croppedPlateImage = imread(croppedPlatePath);
        if size(croppedPlateImage, 3) == 1  % Check if it's grayscale
            croppedPlateImage = repmat(croppedPlateImage, [1, 1, 3]);  % Convert to RGB
        end
        app.Image2.ImageSource = croppedPlateImage;
    end

    charVisPath = fullfile(tempdir, 'char_visualization.png');
    if exist(charVisPath, 'file')
        charVisImage = imread(charVisPath);
        if size(charVisImage, 3) == 1  % Check if it's grayscale
            charVisImage = repmat(charVisImage, [1, 1, 3]);  % Convert to RGB
        end
        app.Image3.ImageSource = charVisImage;
    end
        end

        % Callback function
        function UITableCellEdit(app, event)
            % Get the edited cell's indices (row, column)
    indices = event.Indices;
    
    % Get the new data entered by the user
    newData = event.NewData;
    
    % Update the corresponding cell in UITable with the new data
    row = indices(1);
    col = indices(2);
    
    % Update the UITable's Data property with the new value
    app.UITable.Data{row, col} = newData;
    
    % Optionally, perform any validation or post-processing
    if col == 2  % If the license plate column was edited
        disp(['License plate updated to: ' newData]);
    end
        end

        % Button pushed function: excelBtn
        function excelBtnButtonPushed(app, event)
            if isempty(app.UITable.Data)
        uialert(app.UIFigure, 'Không có dữ liệu để xuất ra Excel.', 'Cảnh báo');
        return;
    end
    
    % Convert table data to MATLAB table
    tableData = app.UITable.Data;
    colNames = app.UITable.ColumnName;
    
    % Create a MATLAB table
    T = cell2table(tableData, 'VariableNames', colNames);
    
    % Define the file name and save it
    [file, path] = uiputfile('*.xlsx', 'Chọn vị trí lưu file Excel');
    if isequal(file, 0) || isequal(path, 0)
        disp('Người dùng đã hủy lưu file.');
        return;
    end
    writetable(T, fullfile(path, file));
    winopen(fullfile(path, file));  % Automatically open the Excel file after saving
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NHGCPanel = uipanel(app.UIFigure);
            app.NHGCPanel.Title = 'ẢNH GỐC';
            app.Image = uiimage(app.NHGCPanel);
            app.anhBtn = uibutton(app.NHGCPanel, 'push');
            app.anhBtn.ButtonPushedFcn = createCallbackFcn(app, @anhBtnButtonPushed, true);
            app.anhBtn.Text = 'Chọn ảnh';
            app.cbinsButton = uibutton(app.UIFigure, 'push');
            app.cbinsButton.ButtonPushedFcn = createCallbackFcn(app, @cbinsButtonPushed, true);
            app.cbinsButton.Text = 'Đọc biển số';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Thời gian'; 'Biển số'; 'Đường dẫn ảnh'};
            app.UITable.RowName = {};
            app.excelBtn = uibutton(app.UIFigure, 'push');
            app.excelBtn.ButtonPushedFcn = createCallbackFcn(app, @excelBtnButtonPushed, true);
            app.excelBtn.Text = 'Xuất file Excel';
            app.NGDNGCBINSXESDNGMATLABLabel = uilabel(app.UIFigure);
            app.NGDNGCBINSXESDNGMATLABLabel.Text = 'ỨNG DỤNG ĐỌC BIỂN SỐ XE SỬ DỤNG MATLAB';
            app.CTBINPanel = uipanel(app.UIFigure);
            app.CTBINPanel.Title = 'CẮT BIỂN';
            app.Image2 = uiimage(app.CTBINPanel);
            app.PHNTCHVNHNDNGKTPanel = uipanel(app.UIFigure);
            app.PHNTCHVNHNDNGKTPanel.Title = 'PHÂN TÁCH VÀ NHẬN DẠNG KÝ TỰ';
            app.Image3 = uiimage(app.PHNTCHVNHNDNGKTPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end